classdef TextRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        Save               matlab.ui.container.Menu
        ImageAxes          matlab.ui.control.UIAxes
        LoadImageButton    matlab.ui.control.Button
        LoadVideoButton    matlab.ui.control.Button
        TextArea2          matlab.ui.control.Label
        ButtonRecognition  matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            if strcmp(imagefile,'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Create histograms based on number of color channels
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,im);
                    
                    
                case 3
                    % Display the truecolor image
                    imagesc(app.ImageAxes,im);
                    
                   
                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end


        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            updateimage(app, 'W:\MATLAB\vkr\images2\1223.jpg');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
               
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end

        % Button pushed function: LoadVideoButton
        function LoadVideoButtonPushed(app, event)

% Папка для хранения кадров
workingDir = 'D:\MATLAB\vkr\';

            % Загрузка видео
v = VideoReader('D:\MATLAB\vkr\test1.ts');

% Создание последовательности кадров
ii = 1;
while hasFrame(v)
    I = readFrame(v);
    filename = [sprintf('%03d',ii) '.jpg'];
    fullname = fullfile(workingDir,'images_eng',filename);
    imwrite(I,fullname)
    
    % Обрезка изображения под бегущую строку
    I2 = imread("W:\MATLAB\vkr\images_rus\001.jpg");
 %     I3 = imcrop(I2,[0 506 720 70]);
    I3 = imcrop(I2,[0 290 640 70]);
    filename2 = [sprintf('%03d',ii) '.jpg'];
    
    % Преобразование в ЧБ
    I4 = colouredToGray(I3);
    % filename2 = '260.jpg';
    fullname2 = fullfile(workingDir,'images1',filename2);
    imwrite(I4,fullname2)
    ii = ii+1;
end
        end

        % Button pushed function: ButtonRecognition
        function ButtonRecognitionPushed(app, event)
            % Поиск областей с текстом
I5 = imread('W:\MATLAB\vkr\images2\001.jpg');
ocrResults=ocr(I5)

% Отображение областей с текстовой информацией
Iocr = insertObjectAnnotation(I5, 'rectangle', ...
    ocrResults.WordBoundingBoxes, ...
    ocrResults.WordConfidences);
recognizedText = ocrResults.Text;    
figure; imshow(Iocr);
     text(0, 140, recognizedText, 'BackgroundColor', [1 1 1]);

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Text Recognition';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Меню';
            app.Save = uimenu(app.Menu);
            app.Save.Text = 'Сохранить как';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, 'Кадр из видеофайла')
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Открыть';
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVideoButtonPushed, true);
            app.LoadVideoButton.Text = 'Кадрировать';
            app.TextArea2 = uilabel(app.UIFigure);
            app.TextArea2.Text = {'Разбить видеофайл'; ' из локальной '; 'папки '; 'на кадры'};
            app.ButtonRecognition = uibutton(app.UIFigure, 'push');
            app.ButtonRecognition.ButtonPushedFcn = createCallbackFcn(app, @ButtonRecognitionPushed, true);
            app.ButtonRecognition.Text = {'Начать '; 'распознавание'};
        end
    end

    methods (Access = public)

        % Construct app
        function app = TextRecognition

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end